classdef DTMF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Cur_input_num         matlab.ui.control.Label
        LabelInput_5          matlab.ui.control.Label
        LabelInput_4          matlab.ui.control.Label
        LabelInput_3          matlab.ui.control.Label
        DecodingOutPut_num_2  matlab.ui.control.Label
        dBLabel               matlab.ui.control.Label
        LabelInput_2          matlab.ui.control.Label
        timeLabel             matlab.ui.control.Label
        Spinner               matlab.ui.control.Spinner
        Label_2               matlab.ui.control.Label
        decodedASequence      matlab.ui.control.Button
        createASequence       matlab.ui.control.Button
        Button_deleteAll      matlab.ui.control.Button
        DecodingOutPut_num    matlab.ui.control.Label
        DecodingOutPut_label  matlab.ui.control.Label
        TimeDominFormula      matlab.ui.control.Label
        Freqs_high            matlab.ui.control.Label
        Freqs_low             matlab.ui.control.Label
        FreqsLabel            matlab.ui.control.Label
        Button_backwords      matlab.ui.control.Button
        LabelInput            matlab.ui.control.Label
        DButton               matlab.ui.control.Button
        CButton               matlab.ui.control.Button
        BButton               matlab.ui.control.Button
        AButton               matlab.ui.control.Button
        Button_12             matlab.ui.control.Button
        Button_11             matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_1              matlab.ui.control.Button
        UIAxesFre             matlab.ui.control.UIAxes
        UIAxesTimeDomin       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
            button1=[697,1209]
            button4=[770,1209]
            button7=[852,1209]
            button11=[941,1209]
            button2=[697,1336]
            button5=[770,1336]
            button8=[852,1336]
            button10=[941,1336]
            button3=[697,1477]
            button6=[770,1477]
            button9=[852,1477]
            button12=[941,1477]
            buttonA_13=[697,1633]
            buttonB_14=[770,1633]
            buttonC_15=[852,1633]
            buttonD_16=[941,1633]
            lowFreqs=[697,770,852,941]
            highFreqs=[1209,1336,1477,1633]
            DecodingOutPut_str=''
            input_num=''
            x=[]
            y=[]
            fs=8000
            T=400
            y_sequence=[]
            last_chat=''
            noise_power=0

           
            generator % Dialog box app
            Receiver
            fileName='file1'
            
            
    end
    
    properties (Access = public)
        Property31 % Description
            fileNamePublic='file1'
            isSuccessPublic
    end


    methods (Access = private)


        function [fLen,freqs] = getTargetFreqs(~,y)

            [~,index1]=max(y(1:length(y)/2));
            y(index1)=0;
            [~,index2]=max(y(1:length(y)/2));
            x_tmp=-3999:20:4000;
            if abs(x_tmp(index1))>abs(x_tmp(index2))
                tmp=x_tmp(index1);
                x_tmp(index1)=x_tmp(index2);
                x_tmp(index2)=tmp;
            end
            freqs=[abs(x_tmp(index1)),abs(x_tmp(index2))];
            fLen = length(freqs);
            
           end
        function [isFind,target,error1,error2] = findTargetBtn(app,fLen,freqs)
        
            isFind = 0;
            target = '';
            error1 = inf; 
            error2 = inf;
            num=1;
            if fLen==2                
                for i=1:4
                    for j=1:4
                        [low,high]=getFreqs(app,num);
                        
                        if abs(freqs(1)-low)<=15 && abs(freqs(2)-high)<=15
                            if num<10
                                target=num2str(num);
                            else
                                switch num
                                case 10
                                    target=  '0';
                                case 11
                                    target=  '*';
                                case 12
                                    target=  '#';
                                case 13
                                    target=  'A';
                                case 14
                                    target = 'B';
                                case 15
                                    target = 'C';
                                case 16
                                    target = 'D';
                                otherwise 
                                    isFind=0;
                                end
                            end
                        else
                        isFind=0;
                        end
                        num=num+1;
                    end
                end
                error1=abs(freqs(1)-low)/low;
                error2=abs(freqs(2)-high)/high;
            end
        
        end

        function [low,high]=getFreqs(app,num)
            switch num
                case 1
                    low=app.button1(1);
                    high=app.button1(2);
                case 2
                    low=app.button2(1);
                    high=app.button2(2);
                case 3
                    low=app.button3(1);
                    high=app.button3(2);
                case 4
                    low=app.button4(1);
                    high=app.button4(2);
                case 5
                    low=app.button5(1);
                    high=app.button5(2);
                case 6
                    low=app.button6(1);
                    high=app.button6(2);
                case 7
                    low=app.button7(1);
                    high=app.button7(2);
                case 8
                    low=app.button8(1);
                    high=app.button8(2);
                case 9
                    low=app.button9(1);
                    high=app.button9(2);
                case 10
                    low=app.button10(1);
                    high=app.button10(2);
                case 11
                    low=app.button11(1);
                    high=app.button11(2);
                case 12
                    low=app.button12(1);
                    high=app.button12(2);
                case 13
                    low=app.buttonA_13(1);
                    high=app.buttonA_13(2);
                case 14
                    low=app.buttonB_14(1);
                    high=app.buttonB_14(2);
                case 15
                    low=app.buttonC_15(1);
                    high=app.buttonC_15(2);
                case 16
                    low=app.buttonD_16(1);
                    high=app.buttonD_16(2);
                otherwise
                    low=0;
                    high=0;
            end
            
        end
        
        function [x,y]=calculate(app,T,fs,low,high)
           n=1:T;
           x=n./fs;
           y=sin(2.*pi.*low.*x)+sin(2.*pi.*high.*x);
           if app.noise_power ~= 0
                [m,n]=size(y);
                noise=wgn(m,n,app.noise_power);
                y = y + noise;
            end
        end
        function [y]=FFT(~,y)
            y=fftshift(abs(fft(y)))./(400/2)./(400/2);
        end
        
        function drawTimeDomin(~,axes,x,y,style)
            plot(axes,x,y,style)
        end
        function drawFre(~,axes,~,y,style)

            x=-3999:20:4000;
            plot(axes,x,y,style)   
        end
        
        
        function [y_TimeDomin] = pushBtm(app,num)
           numFreqs=num;
           
           [low,high]=getFreqs(app,numFreqs);
           
           [app.x,app.y]=calculate(app,app.T,app.fs,low,high);
           
           drawTimeDomin(app,app.UIAxesTimeDomin,app.x,app.y,'');
           
           sound(app.y)
           y_TimeDomin=app.y;
           
           app.y=FFT(app,app.y);
           
           drawFre(app,app.UIAxesFre,app.x,app.y,'--o');
           
           [fLen,freqs]=getTargetFreqs(app,app.y);
           
           
           [~,target,error1,error2] = findTargetBtn(app,fLen,freqs);
           
           app.input_num=sprintf('%s%s',app.input_num,num2str(num));
           app.DecodingOutPut_num_2.Text=app.input_num;
           
           app.Cur_input_num.Text=num2str(num)
           app.DecodingOutPut_str=sprintf('%s%s',app.DecodingOutPut_str,target);
           app.DecodingOutPut_num.Text=app.DecodingOutPut_str;
           app.TimeDominFormula.Text=sprintf('%s%d%s%d%s','Y=sin(2π*',low,'X)+sin(2π*',high,'X)');
           app.Freqs_low.Text=num2str(freqs(1))+"Hz";
           app.Freqs_high.Text=num2str(freqs(2))+"HZ";
           app.last_chat=target;


        end
        function generateOneSequence(app,y_TimeDome)
           y_TimeDome=[zeros(1,200),y_TimeDome,zeros(1,200)];
           app.y_sequence=[app.y_sequence,y_TimeDome];
           
        end
        function deleteOneSequence(app,y_sequence)
            [y,~]=audioread('sound_new.wav');
            sound(y)
            if length(y_sequence)>=750
                y_sequence(length(y_sequence)-799:length(y_sequence))=[];
                app.DecodingOutPut_str=app.DecodingOutPut_str(1:length(app.DecodingOutPut_str)-1);
                app.DecodingOutPut_num.Text=app.DecodingOutPut_str;
            end
            
            
        end
        function deleteAllSequence(app)
            app.y_sequence=[];
            app.DecodingOutPut_str='';
            app.DecodingOutPut_num.Text='';
            [y,~]=audioread('sound_new.wav');
            sound(y)
        end
        
    end
    
    methods (Access = public)
        
        function isSuccess(app,success,name)
            app.fileNamePublic=name;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
           [y_TimeDome]=pushBtm(app,1);
           generateOneSequence(app,y_TimeDome);            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [y_TimeDome]=pushBtm(app,2);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [y_TimeDome]=pushBtm(app,3);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [y_TimeDome]=pushBtm(app,4);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            [y_TimeDome]=pushBtm(app,5);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            [y_TimeDome]=pushBtm(app,6);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            [y_TimeDome]=pushBtm(app,7);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            [y_TimeDome]=pushBtm(app,8);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            [y_TimeDome]=pushBtm(app,9);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            [y_TimeDome]=pushBtm(app,10);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            [y_TimeDome]=pushBtm(app,11);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            [y_TimeDome]=pushBtm(app,12);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            [y_TimeDome]=pushBtm(app,13);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            [y_TimeDome]=pushBtm(app,14);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            [y_TimeDome]=pushBtm(app,15);            
            generateOneSequence(app,y_TimeDome);
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            [y_TimeDome]=pushBtm(app,16);            
            generateOneSequence(app,y_TimeDome);
        end

        % Callback function
        function Button_deleteAllValueChanged(app, event)
            deleteAllSequence(app);        
        end

        % Button pushed function: Button_backwords
        function Button_backwordsPushed(app, event)
            deleteOneSequence(app,app.y_sequence);
        end

        % Button pushed function: Button_deleteAll
        function Button_deleteAllPushed(app, event)
            deleteAllSequence(app);
        end

        % Button pushed function: createASequence
        function createASequenceButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.createASequence.Enable = 'off';
            app.fileName=app.fileNamePublic
            % Get szvalue and cvalue
            % ....
    
            % Call dialog box with input values
            app.generator = generator(app,app.y_sequence,app.fileName);
            isSuccess(app,app.isSuccessPublic,app.fileNamePublic);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            
            % Delete both apps
            delete(app.generator)
            delete(app)
        end

        % Button pushed function: decodedASequence
        function decodedASequenceButtonPushed(app, event)
%             app.decodedASequence.Enable = 'off';  
            app.fileName=app.fileNamePublic;

            app.Receiver = receiver(app,app.fileNamePublic);
%             app.createASequence.Enable = 'on';
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.noise_power = app.Spinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesTimeDomin = uiaxes(app.UIFigure);
            title(app.UIAxesTimeDomin, '当前号码时域图')
            xlabel(app.UIAxesTimeDomin, 'X')
            ylabel(app.UIAxesTimeDomin, 'Y')
            app.UIAxesFre = uiaxes(app.UIFigure);
            title(app.UIAxesFre, '当前号码频谱图')
            xlabel(app.UIAxesFre, 'X')
            ylabel(app.UIAxesFre, 'Y')
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '*';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.LabelInput = uilabel(app.UIFigure);
            app.LabelInput.Text = '当前输入数字:';
            app.Button_backwords = uibutton(app.UIFigure, 'push');
            app.Button_backwords.ButtonPushedFcn = createCallbackFcn(app, @Button_backwordsPushed, true);
            app.Button_backwords.Text = '退格';
            app.FreqsLabel = uilabel(app.UIFigure);
            app.FreqsLabel.Text = 'Freqs:';
            app.Freqs_low = uilabel(app.UIFigure);
            app.Freqs_low.Text = '0';
            app.Freqs_high = uilabel(app.UIFigure);
            app.Freqs_high.Text = '0';
            app.TimeDominFormula = uilabel(app.UIFigure);
            app.TimeDominFormula.Text = '0';
            app.DecodingOutPut_label = uilabel(app.UIFigure);
            app.DecodingOutPut_label.Text = '解码输出:';
            app.DecodingOutPut_num = uilabel(app.UIFigure);
            app.DecodingOutPut_num.Text = '';
            app.Button_deleteAll = uibutton(app.UIFigure, 'push');
            app.Button_deleteAll.ButtonPushedFcn = createCallbackFcn(app, @Button_deleteAllPushed, true);
            app.Button_deleteAll.Text = '清空';
            app.createASequence = uibutton(app.UIFigure, 'push');
            app.createASequence.ButtonPushedFcn = createCallbackFcn(app, @createASequenceButtonPushed, true);
            app.createASequence.Text = '生成当前序列图';
            app.decodedASequence = uibutton(app.UIFigure, 'push');
            app.decodedASequence.ButtonPushedFcn = createCallbackFcn(app, @decodedASequenceButtonPushed, true);
            app.decodedASequence.Text = '解码序列';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '信噪比';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.timeLabel = uilabel(app.UIFigure);
            app.timeLabel.Text = 'time:';
            app.LabelInput_2 = uilabel(app.UIFigure);
            app.LabelInput_2.Text = '已输入号码:';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.DecodingOutPut_num_2 = uilabel(app.UIFigure);
            app.DecodingOutPut_num_2.Text = '';
            app.LabelInput_3 = uilabel(app.UIFigure);
            app.LabelInput_3.Text = '当前数字编码表达式：';
            app.LabelInput_4 = uilabel(app.UIFigure);
            app.LabelInput_4.Text = '低频频率：';
            app.LabelInput_5 = uilabel(app.UIFigure);
            app.LabelInput_5.Text = '高频频率：';
            app.Cur_input_num = uilabel(app.UIFigure);
            app.Cur_input_num.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DTMF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end