classdef generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        fileName_EditField  matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        options             matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        save                matlab.ui.control.Button
        playAudio           matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
            y_sequence=[];
            fileName=''
            DTMF % Main app object
            success=1
    end
    
    methods (Access = private)
        
        function drawTimeDomin(app,axes,y_sequence,style)
            
            x=1:length(y_sequence);
            plot(axes,x,y_sequence,style)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, y_sequence, fileName)
            % Store main app object
            app.DTMF = mainapp;

            % Process sz and c inputs
            app.y_sequence=y_sequence;
            
            drawTimeDomin(app,app.UIAxes,y_sequence,'-.')
            app.fileName='file_save';
            app.fileName_EditField.Value=fileName;
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            % Call main app's public function
            isSuccess(app.DTMF,app.success,app.fileName);
            
            app.DTMF.createASequence.Enable = 'on';
            
            save_name=sprintf("%s.%s",app.fileName,app.options.Value)
         
            audiowrite(save_name,app.y_sequence,8000)
     
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
             app.DTMF.createASequence.Enable = 'on';
            
            delete(app)
        end

        % Button pushed function: playAudio
        function playAudioButtonPushed(app, event)
            sound(app.y_sequence)
        end

        % Value changed function: options
        function optionsValueChanged(app, event)
            value = app.options.Value;

        end

        % Value changed function: fileName_EditField
        function fileName_EditFieldValueChanged(app, event)
            
            value = app.fileName_EditField.Value;
            app.fileName=value;
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '当前号码时序图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.playAudio = uibutton(app.UIFigure, 'push');
            app.playAudio.ButtonPushedFcn = createCallbackFcn(app, @playAudioButtonPushed, true);
            app.playAudio.Text = '播放';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件保存类型';
            app.options = uidropdown(app.UIFigure);
            app.options.Items = {'wav', 'FLAC', 'OGG'};
            app.options.ValueChangedFcn = createCallbackFcn(app, @optionsValueChanged, true);
            app.options.Value = 'wav';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '文件名:';
            app.fileName_EditField = uieditfield(app.UIFigure, 'text');
            app.fileName_EditField.ValueChangedFcn = createCallbackFcn(app, @fileName_EditFieldValueChanged, true);
            app.fileName_EditField.Value = 'file1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end